classdef AudioEquilizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioEquilizerUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EquilizerTab                   matlab.ui.container.Tab
        SliderLabel                    matlab.ui.control.Label
        band1Slider                    matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        band2Slider                    matlab.ui.control.Slider
        Slider_3Label                  matlab.ui.control.Label
        band3Slider                    matlab.ui.control.Slider
        Slider_4Label                  matlab.ui.control.Label
        band4Slider                    matlab.ui.control.Slider
        kSliderLabel                   matlab.ui.control.Label
        band5Slider                    matlab.ui.control.Slider
        kSlider_2Label                 matlab.ui.control.Label
        band6Slider                    matlab.ui.control.Slider
        kSlider_3Label                 matlab.ui.control.Label
        band7Slider                    matlab.ui.control.Slider
        kSlider_4Label                 matlab.ui.control.Label
        band8Slider                    matlab.ui.control.Slider
        kSlider_5Label                 matlab.ui.control.Label
        band9Slider                    matlab.ui.control.Slider
        kLabel                         matlab.ui.control.Label
        band10Slider                   matlab.ui.control.Slider
        FilterTypeSwitch               matlab.ui.control.ToggleSwitch
        FIR_lamp                       matlab.ui.control.Lamp
        IIR_lamp                       matlab.ui.control.Lamp
        GaindbLabel                    matlab.ui.control.Label
        saveBtn                        matlab.ui.control.Button
        chooseBtn                      matlab.ui.control.Button
        OutputFskHzSpinnerLabel        matlab.ui.control.Label
        OutputFskHzSpinner             matlab.ui.control.Spinner
        applyBtn                       matlab.ui.control.Button
        playBtn                        matlab.ui.control.Button
        stopBtn                        matlab.ui.control.Button
        playOriginalBtn                matlab.ui.control.Button
        resetBtn                       matlab.ui.control.Button
        InputVSOutputTab               matlab.ui.container.Tab
        plotInOut                      matlab.ui.control.UIAxes
        inOutRadio                     matlab.ui.container.ButtonGroup
        InputtimedomainButton          matlab.ui.control.RadioButton
        InputfrequencydomainButton     matlab.ui.control.RadioButton
        OutputtimedomainButton         matlab.ui.control.RadioButton
        OutputfrequencydomainButton    matlab.ui.control.RadioButton
        InputOutputSignalsanalysisLabel  matlab.ui.control.Label
        Filter1Tab                     matlab.ui.container.Tab
        f1Plot                         matlab.ui.control.UIAxes
        f1Radio                        matlab.ui.container.ButtonGroup
        plotF1GainPhase                matlab.ui.control.RadioButton
        plotF1Impulse                  matlab.ui.control.RadioButton
        plotF1Step                     matlab.ui.control.RadioButton
        plotF1pz                       matlab.ui.control.RadioButton
        plotF1outTime                  matlab.ui.control.RadioButton
        plotF1outFreq                  matlab.ui.control.RadioButton
        Hzto170HzfilteranalysisLabel   matlab.ui.control.Label
        Filter2Tab                     matlab.ui.container.Tab
        f2Plot                         matlab.ui.control.UIAxes
        f2Radio                        matlab.ui.container.ButtonGroup
        plotF2GainPhase                matlab.ui.control.RadioButton
        plotF2Impulse                  matlab.ui.control.RadioButton
        plotF2Step                     matlab.ui.control.RadioButton
        plotF2pz                       matlab.ui.control.RadioButton
        plotF2outTime                  matlab.ui.control.RadioButton
        plotF2outFreq                  matlab.ui.control.RadioButton
        Hzto310HzfilteranalysisLabel   matlab.ui.control.Label
        Filter3Tab                     matlab.ui.container.Tab
        f3Plot                         matlab.ui.control.UIAxes
        f3Radio                        matlab.ui.container.ButtonGroup
        plotF3GainPhase                matlab.ui.control.RadioButton
        plotF3Impulse                  matlab.ui.control.RadioButton
        plotF3Step                     matlab.ui.control.RadioButton
        plotF3pz                       matlab.ui.control.RadioButton
        plotF3outTime                  matlab.ui.control.RadioButton
        plotF3outFreq                  matlab.ui.control.RadioButton
        Hzto600HzfilteranalysisLabel   matlab.ui.control.Label
        Filter4Tab                     matlab.ui.container.Tab
        f4Plot                         matlab.ui.control.UIAxes
        f4Radio                        matlab.ui.container.ButtonGroup
        plotF4GainPhase                matlab.ui.control.RadioButton
        plotF4Impulse                  matlab.ui.control.RadioButton
        plotF4Step                     matlab.ui.control.RadioButton
        plotF4pz                       matlab.ui.control.RadioButton
        plotF4outTime                  matlab.ui.control.RadioButton
        plotF4outFreq                  matlab.ui.control.RadioButton
        Hzto1kHzfilteranalysisLabel    matlab.ui.control.Label
        Filter5Tab                     matlab.ui.container.Tab
        f5Plot                         matlab.ui.control.UIAxes
        f5Radio                        matlab.ui.container.ButtonGroup
        plotF5GainPhase                matlab.ui.control.RadioButton
        plotF5Impulse                  matlab.ui.control.RadioButton
        plotF5Step                     matlab.ui.control.RadioButton
        plotF5pz                       matlab.ui.control.RadioButton
        plotF5outTime                  matlab.ui.control.RadioButton
        plotF5outFreq                  matlab.ui.control.RadioButton
        kHzto3kHzfilteranalysisLabel   matlab.ui.control.Label
        Filter6Tab                     matlab.ui.container.Tab
        f6Plot                         matlab.ui.control.UIAxes
        f6Radio                        matlab.ui.container.ButtonGroup
        plotF6GainPhase                matlab.ui.control.RadioButton
        plotF6Impulse                  matlab.ui.control.RadioButton
        plotF6Step                     matlab.ui.control.RadioButton
        plotF6pz                       matlab.ui.control.RadioButton
        plotF6outTime                  matlab.ui.control.RadioButton
        plotF6outFreq                  matlab.ui.control.RadioButton
        kHzto6kHzfilteranalysisLabel   matlab.ui.control.Label
        Filter7Tab                     matlab.ui.container.Tab
        f7Plot                         matlab.ui.control.UIAxes
        f7Radio                        matlab.ui.container.ButtonGroup
        plotF7GainPhase                matlab.ui.control.RadioButton
        plotF7Impulse                  matlab.ui.control.RadioButton
        plotF7Step                     matlab.ui.control.RadioButton
        plotF7pz                       matlab.ui.control.RadioButton
        plotF7outTime                  matlab.ui.control.RadioButton
        plotF7outFreq                  matlab.ui.control.RadioButton
        kHzto12kHzfilteranalysisLabel  matlab.ui.control.Label
        Filter8Tab                     matlab.ui.container.Tab
        f8Plot                         matlab.ui.control.UIAxes
        f8Radio                        matlab.ui.container.ButtonGroup
        plotF8GainPhase                matlab.ui.control.RadioButton
        plotF8Impulse                  matlab.ui.control.RadioButton
        plotF8Step                     matlab.ui.control.RadioButton
        plotF8pz                       matlab.ui.control.RadioButton
        plotF8outTime                  matlab.ui.control.RadioButton
        plotF8outFreq                  matlab.ui.control.RadioButton
        kHzto14kHzfilteranalysisLabel  matlab.ui.control.Label
        Filter9Tab                     matlab.ui.container.Tab
        f9Plot                         matlab.ui.control.UIAxes
        f9Radio                        matlab.ui.container.ButtonGroup
        plotF9GainPhase                matlab.ui.control.RadioButton
        plotF9Impulse                  matlab.ui.control.RadioButton
        plotF9Step                     matlab.ui.control.RadioButton
        plotF9pz                       matlab.ui.control.RadioButton
        plotF9outTime                  matlab.ui.control.RadioButton
        plotF9outFreq                  matlab.ui.control.RadioButton
        kHzto16kHzfilteranalysisLabel  matlab.ui.control.Label
        Filter10Tab                    matlab.ui.container.Tab
        f10Plot                        matlab.ui.control.UIAxes
        f10Radio                       matlab.ui.container.ButtonGroup
        plotF10GainPhase               matlab.ui.control.RadioButton
        plotF10Impulse                 matlab.ui.control.RadioButton
        plotF10Step                    matlab.ui.control.RadioButton
        plotF10pz                      matlab.ui.control.RadioButton
        plotF10outTime                 matlab.ui.control.RadioButton
        plotF10outFreq                 matlab.ui.control.RadioButton
        above16kHzfilteranalysisLabel  matlab.ui.control.Label
        ChooseasoundfiletoeditLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % input sound signal arguments
        inputSignal;
        inFs;
        
        % filters
        fb1; fb2; fb3; fb4; fb5; fb6; fb7; fb8; fb9; fb10;
        fa1; fa2; fa3; fa4; fa5; fa6; fa7; fa8; fa9; fa10;
        
        % output sound signal arguments
        outputSignal;
        band0; band170; band310; band600; band1k; band3k; band6k; band12k; band14k; band16k;
        
        % gui user inputs
        gain1  = 0;
        gain2  = 0;
        gain3  = 0;
        gain4  = 0;
        gain5  = 0;
        gain6  = 0;
        gain7  = 0;
        gain8  = 0;
        gain9  = 0;
        gain10 = 0;
        ftype = 'IIR';
        outFs;
        
        % sound player
        player;
        soundFs;
    
    end
    
    methods (Access = private)
        
        function performFiltration(app)
            initializeFilters(app);
            app.band0   = filter(app.fb1, app.fa1, app.inputSignal) * db2mag(app.gain1);
            app.band170 = filter(app.fb2, app.fa2, app.inputSignal) * db2mag(app.gain2);
            app.band310 = filter(app.fb3, app.fa3, app.inputSignal) * db2mag(app.gain3);
            app.band600 = filter(app.fb4, app.fa4, app.inputSignal) * db2mag(app.gain4);
            app.band1k  = filter(app.fb5, app.fa5, app.inputSignal) * db2mag(app.gain5);
            app.band3k  = filter(app.fb6, app.fa6, app.inputSignal) * db2mag(app.gain6);
            app.band6k  = filter(app.fb7, app.fa7, app.inputSignal) * db2mag(app.gain7);
            app.band12k = filter(app.fb8, app.fa8, app.inputSignal) * db2mag(app.gain8);
            app.band14k = filter(app.fb9, app.fa9, app.inputSignal) * db2mag(app.gain9);
            app.band16k = filter(app.fb10, app.fa10, app.inputSignal) * db2mag(app.gain10);
%             app.band0   = filter(app.fb1, app.fa1, app.inputSignal) * (10^(app.gain1/20));
%             app.band170 = filter(app.fb2, app.fa2, app.inputSignal) * (10^(app.gain2/20));
%             app.band310 = filter(app.fb3, app.fa3, app.inputSignal) * (10^(app.gain3/20));
%             app.band600 = filter(app.fb4, app.fa4, app.inputSignal) * (10^(app.gain4/20));
%             app.band1k  = filter(app.fb5, app.fa5, app.inputSignal) * (10^(app.gain5/20));
%             app.band3k  = filter(app.fb6, app.fa6, app.inputSignal) * (10^(app.gain6/20));
%             app.band6k  = filter(app.fb7, app.fa7, app.inputSignal) * (10^(app.gain7/20));
%             app.band12k = filter(app.fb8, app.fa8, app.inputSignal) * (10^(app.gain8/20));
%             app.band14k = filter(app.fb9, app.fa9, app.inputSignal) * (10^(app.gain9/20));
%             app.band16k = filter(app.fb10, app.fa10, app.inputSignal) * (10^(app.gain10/20));
            app.outputSignal = app.band0 + app.band170 + app.band310 + app.band600 + app.band1k + app.band3k + app.band6k + app.band12k + app.band14k + app.band16k;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Filters Initialization functions %
        function initializeFilters(app)
            if(app.ftype == 'IIR')
                IIRinitialization(app);
            else
                FIRinitialization(app);
            end
        end
        
        function IIRinitialization(app)
            [app.fb1, app.fa1]   = getIirFilter(app, 1);
            [app.fb2, app.fa2]   = getIirFilter(app, 2);
            [app.fb3, app.fa3]   = getIirFilter(app, 3);
            [app.fb4, app.fa4]   = getIirFilter(app, 4);
            [app.fb5, app.fa5]   = getIirFilter(app, 5);
            [app.fb6, app.fa6]   = getIirFilter(app, 6);
            [app.fb7, app.fa7]   = getIirFilter(app, 7);
            [app.fb8, app.fa8]   = getIirFilter(app, 8);
            [app.fb9, app.fa9]   = getIirFilter(app, 9);
            [app.fb10, app.fa10] = getIirFilter(app, 10);
        end
        
        function FIRinitialization(app)
            [app.fb1, app.fa1]   = getFirFilter(app, 1);
            [app.fb2, app.fa2]   = getFirFilter(app, 2);
            [app.fb3, app.fa3]   = getFirFilter(app, 3);
            [app.fb4, app.fa4]   = getFirFilter(app, 4);
            [app.fb5, app.fa5]   = getFirFilter(app, 5);
            [app.fb6, app.fa6]   = getFirFilter(app, 6);
            [app.fb7, app.fa7]   = getFirFilter(app, 7);
            [app.fb8, app.fa8]   = getFirFilter(app, 8);
            [app.fb9, app.fa9]   = getFirFilter(app, 9);
            [app.fb10, app.fa10] = getFirFilter(app, 10);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % IIR Filters Factory %
        function [b, a] = getIirFilter(app, index)
            N = 4;
            fs = app.outFs;
            switch(index)
                case 1
                    [b, a] = iLPF(app, N, 170, fs);
                case 2
                    [b, a] = iBPF(app, N, 170, 310, fs);
                case 3
                    [b, a] = iBPF(app, N, 310, 600, fs);
                case 4
                    [b, a] = iBPF(app, N, 600, 1000, fs);
                case 5
                    [b, a] = iBPF(app, N, 1000, 3000, fs);
                case 6
                    [b, a] = iBPF(app, N, 3000, 6000, fs);
                case 7
                    [b, a] = iBPF(app, N, 6000, 12000, fs);
                case 8
                    [b, a] = iBPF(app, N, 12000, 14000, fs);
                case 9
                    [b, a] = iBPF(app, N, 14000, 16000, fs);
                case 10
                    [b, a] = iHPF(app, N, 16000, fs);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % FIR Filters Factory %
        function [b, a] = getFirFilter(app, index)
            N = 35;
            fs = app.outFs;
            switch(index)
                case 1
                    [b, a] = fLPF(app, N, 170, fs);
                case 2
                    [b, a] = fBPF(app, N, 170, 310, fs);
                case 3
                    [b, a] = fBPF(app, N, 310, 600, fs);
                case 4
                    [b, a] = fBPF(app, N, 600, 1000, fs);
                case 5
                    [b, a] = fBPF(app, N, 1000, 3000, fs);
                case 6
                    [b, a] = fBPF(app, N, 3000, 6000, fs);
                case 7
                    [b, a] = fBPF(app, N, 6000, 12000, fs);
                case 8
                    [b, a] = fBPF(app, N, 12000, 14000, fs);
                case 9
                    [b, a] = fBPF(app, N, 14000, 16000, fs);
                case 10
                    [b, a] = fHPF(app, N, 16000, fs);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % IIR Filters creation functions %
        function [b, a] = iLPF(~, N, fc, fs)       % order, cutoff freq, sampling freq
            
            wn = (fc*2) / fs;           % wc = 2*pi*fc/fs   =>  wn = wc/pi 
            [b, a] = butter(N, wn);     % result = [b, a]   =>  num & denom of TF
        end

        function [b, a] = iBPF(~, N, f1, f2, fs)   % orded, band start freq, band end freq, sampling freq
            
            wn1 = (f1*2) / fs;
            wn2 = (f2*2) / fs;
            wn = [wn1 wn2];
            [b, a] = butter(N, wn);
        end
        
        function [b, a] = iHPF(~, N, fc, fs)       % order, cutoff freq, sampling freq
            
            wn = (fc*2) / fs;           % wc = 2*pi*fc/fs   =>  wn = wc/pi 
            [b, a] = butter(N, wn, 'high');     % result = [b, a]   =>  num & denom of TF
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % FIR Filters creation functions %
        function [b, a] = fLPF(~, N, fc, fs)         % order, cutoff freq, gain
        
            wn = (fc*2) / fs;           % wc = 2*pi*fc/fs   =>  wn = wc/pi 
            [b, a] = fir1(N, wn);     % result = [b, a]   =>  num & denom of TF
        end
        
        function [b, a] = fBPF(~, N, f1, f2, fs)     % orded, band start freq, band end freq, gain
        
            wn1 = (f1*2) / fs;
            wn2 = (f2*2) / fs;
            wn = [wn1 wn2];
            [b, a] = fir1(N, wn);
        end
        
        function [b, a] = fHPF(~, N, fc, fs)         % order, cutoff freq, gain
        
            wn = (fc*2) / fs;           % wc = 2*pi*fc/fs   =>  wn = wc/pi 
            [b, a] = fir1(N, wn, 'high');     % result = [b, a]   =>  num & denom of TF
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Analusis & Plotting functions %
        function plotGainPhase(~, b, a)
            freqz(b, a);
            grid on;
        end
        
        function plotImpulseResponce(~, b, a)
            impz(b, a);
            grid on;
        end
        
        function plotStepResponce(~, b, a)
            stepz(b, a);
            grid on;
        end
        
        function plotPZmap(~, b, a)
            transferFn = tf(b, a);
            pzmap(transferFn);
            grid on;
        end
        
        function plotTimeDomain(~, axes, ft, fs)
            tstart = 0;
            % length(ft) = (tend - tstart) * fs
            tend = (length(ft) / fs) + tstart;
            t = linspace(tstart, tend, length(ft));
            plot(axes, t, ft);
            grid on;
        end
        
        function plotFreqDomain(~, axes, fn, fs)
            % frequency base vector
            freq = linspace(-fs/2, fs/2, length(fn));
            FN = fft(fn);
            FN = fftshift(FN);
            plot(axes, freq, FN);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.AudioEquilizerUIFigure, 'center');
        end

        % Value changed function: FilterTypeSwitch
        function FilterTypeSwitchValueChanged(app, event)
            value = app.FilterTypeSwitch.Value;
            if(value == 'IIR')
                app.IIR_lamp.Color = [0.00,1.00,0.00];  %green
                app.FIR_lamp.Color = [0.50,0.50,0.50];  %grey
                app.ftype = 'IIR';
            else
                app.FIR_lamp.Color = [0.00,1.00,0.00];  % green
                app.IIR_lamp.Color = [0.50,0.50,0.50];  %grey
                app.ftype = 'FIR';
            end
        end

        % Button pushed function: chooseBtn
        function chooseBtnButtonPushed(app, event)
            filter = {'.wav'};
            [name, path] = uigetfile(filter);
            directory = [path name];
            app.AudioEquilizerUIFigure.Visible = false;
            app.AudioEquilizerUIFigure.Visible = true;
            if(name ~= 0)
                [input, fs] = audioread(directory);
                app.inputSignal = input;
                app.outputSignal = input;
                app.inFs = fs;
                if(fs < 32010)
                    app.outFs = 32010;
                else
                    app.outFs = fs;
                end
                app.soundFs = app.inFs;
                app.OutputFskHzSpinner.Value = app.inFs/1000;
                app.playBtn.Enable = true;
                app.applyBtn.Enable = true;
                app.saveBtn.Enable = true;
                app.stopBtn.Enable = true;
                app.playOriginalBtn.Enable = true;
                app.resetBtn.Enable = true;
            end
        end

        % Button pushed function: playBtn
        function playBtnButtonPushed(app, event)
            app.player = audioplayer(app.outputSignal, app.soundFs);
            stop(app.player);
            play(app.player);
            %clear playsnd;
            %sound(app.outputSignal, app.outFs);
        end

        % Value changed function: OutputFskHzSpinner
        function OutputFskHzSpinnerValueChanged(app, event)
            value = app.OutputFskHzSpinner.Value;
            app.soundFs = value * 1000;
        end

        % Button pushed function: applyBtn
        function applyBtnButtonPushed(app, event)
            performFiltration(app);
            app.inOutRadio.Visible = true;
            app.f1Radio.Visible = true;
            app.f2Radio.Visible = true;
            app.f3Radio.Visible = true;
            app.f4Radio.Visible = true;
            app.f5Radio.Visible = true;
            app.f6Radio.Visible = true;
            app.f7Radio.Visible = true;
            app.f8Radio.Visible = true;
            app.f9Radio.Visible = true;
            app.f10Radio.Visible = true;
        end

        % Value changed function: band1Slider
        function band1SliderValueChanged(app, event)
            value = app.band1Slider.Value;
            app.gain1 = value;
        end

        % Value changed function: band2Slider
        function band2SliderValueChanged(app, event)
            value = app.band2Slider.Value;
            app.gain2 = value;
        end

        % Value changed function: band3Slider
        function band3SliderValueChanged(app, event)
            value = app.band3Slider.Value;
            app.gain3 = value;
        end

        % Value changed function: band4Slider
        function band4SliderValueChanged(app, event)
            value = app.band4Slider.Value;
            app.gain4 = value;
        end

        % Value changed function: band5Slider
        function band5SliderValueChanged(app, event)
            value = app.band5Slider.Value;
            app.gain5 = value;
        end

        % Value changed function: band6Slider
        function band6SliderValueChanged(app, event)
            value = app.band6Slider.Value;
            app.gain6 = value;
        end

        % Value changed function: band7Slider
        function band7SliderValueChanged(app, event)
            value = app.band7Slider.Value;
            app.gain7 = value;
        end

        % Value changed function: band8Slider
        function band8SliderValueChanged(app, event)
            value = app.band8Slider.Value;
            app.gain8 = value;
        end

        % Value changed function: band9Slider
        function band9SliderValueChanged(app, event)
            value = app.band9Slider.Value;
            app.gain9 = value;
        end

        % Value changed function: band10Slider
        function band10SliderValueChanged(app, event)
            value = app.band10Slider.Value;
            app.gain10 = value;
        end

        % Selection changed function: inOutRadio
        function inOutRadioSelectionChanged(app, event)
            selectedButton = app.inOutRadio.SelectedObject;
            if(selectedButton == app.InputtimedomainButton)
                plotTimeDomain(app, app.plotInOut, app.inputSignal, app.inFs);
            elseif(selectedButton == app.InputfrequencydomainButton)
                plotFreqDomain(app, app.plotInOut, app.inputSignal, app.inFs);
            elseif(selectedButton == app.OutputtimedomainButton)
                plotTimeDomain(app, app.plotInOut, app.outputSignal, app.soundFs);
            elseif(selectedButton == app.OutputfrequencydomainButton)
                plotFreqDomain(app, app.plotInOut, app.outputSignal, app.soundFs);
            end
        end

        % Selection changed function: f1Radio
        function f1RadioSelectionChanged(app, event)
            selectedButton = app.f1Radio.SelectedObject;
            if(selectedButton == app.plotF1GainPhase)
                figure;
                plotGainPhase(app, app.fb1, app.fa1);
            elseif(selectedButton == app.plotF1Impulse)
                figure;
                plotImpulseResponce(app, app.fb1, app.fa1);
            elseif(selectedButton == app.plotF1Step)
                figure;
                plotStepResponce(app, app.fb1, app.fa1);
            elseif(selectedButton == app.plotF1pz)
                figure;
                plotPZmap(app, app.fb1, app.fa1);
            elseif(selectedButton == app.plotF1outTime)
                plotTimeDomain(app, app.f1Plot, app.band0, app.soundFs);
            elseif(selectedButton == app.plotF1outFreq)
                plotFreqDomain(app, app.f1Plot, app.band0, app.soundFs);
            end
        end

        % Selection changed function: f2Radio
        function f2RadioSelectionChanged(app, event)
            selectedButton = app.f2Radio.SelectedObject;
            if(selectedButton == app.plotF2GainPhase)
                figure;
                plotGainPhase(app, app.fb2, app.fa2);
            elseif(selectedButton == app.plotF2Impulse)
                figure;
                plotImpulseResponce(app, app.fb2, app.fa2);
            elseif(selectedButton == app.plotF2Step)
                figure;
                plotStepResponce(app, app.fb2, app.fa2);
            elseif(selectedButton == app.plotF2pz)
                figure;
                plotPZmap(app, app.fb2, app.fa2);
            elseif(selectedButton == app.plotF2outTime)
                plotTimeDomain(app, app.f2Plot, app.band170, app.soundFs);
            elseif(selectedButton == app.plotF2outFreq)
                plotFreqDomain(app, app.f2Plot, app.band170, app.soundFs);
            end
        end

        % Selection changed function: f3Radio
        function f3RadioSelectionChanged(app, event)
            selectedButton = app.f3Radio.SelectedObject;
            if(selectedButton == app.plotF3GainPhase)
                figure;
                plotGainPhase(app, app.fb3, app.fa3);
            elseif(selectedButton == app.plotF3Impulse)
                figure;
                plotImpulseResponce(app, app.fb3, app.fa3);
            elseif(selectedButton == app.plotF3Step)
                figure;
                plotStepResponce(app, app.fb3, app.fa3);
            elseif(selectedButton == app.plotF3pz)
                figure;
                plotPZmap(app, app.fb3, app.fa3);
            elseif(selectedButton == app.plotF3outTime)
                plotTimeDomain(app, app.f3Plot, app.band310, app.soundFs);
            elseif(selectedButton == app.plotF3outFreq)
                plotFreqDomain(app, app.f3Plot, app.band310, app.soundFs);
            end
        end

        % Selection changed function: f4Radio
        function f4RadioSelectionChanged(app, event)
            selectedButton = app.f4Radio.SelectedObject;
            if(selectedButton == app.plotF4GainPhase)
                figure;
                plotGainPhase(app, app.fb4, app.fa4);
            elseif(selectedButton == app.plotF4Impulse)
                figure;
                plotImpulseResponce(app, app.fb4, app.fa4);
            elseif(selectedButton == app.plotF4Step)
                figure;
                plotStepResponce(app, app.fb4, app.fa4);
            elseif(selectedButton == app.plotF4pz)
                figure;
                plotPZmap(app, app.fb4, app.fa4);
            elseif(selectedButton == app.plotF4outTime)
                plotTimeDomain(app, app.f4Plot, app.band600, app.soundFs);
            elseif(selectedButton == app.plotF4outFreq)
                plotFreqDomain(app, app.f4Plot, app.band600, app.soundFs);
            end
        end

        % Selection changed function: f5Radio
        function f5RadioSelectionChanged(app, event)
            selectedButton = app.f5Radio.SelectedObject;
            if(selectedButton == app.plotF5GainPhase)
                figure;
                plotGainPhase(app, app.fb5, app.fa5);
            elseif(selectedButton == app.plotF5Impulse)
                figure;
                plotImpulseResponce(app, app.fb5, app.fa5);
            elseif(selectedButton == app.plotF5Step)
                figure;
                plotStepResponce(app, app.fb5, app.fa5);
            elseif(selectedButton == app.plotF5pz)
                figure;
                plotPZmap(app, app.fb5, app.fa5);
            elseif(selectedButton == app.plotF5outTime)
                plotTimeDomain(app, app.f5Plot, app.band1k, app.soundFs);
            elseif(selectedButton == app.plotF5outFreq)
                plotFreqDomain(app, app.f5Plot, app.band1k, app.soundFs);
            end
        end

        % Selection changed function: f6Radio
        function f6RadioSelectionChanged(app, event)
            selectedButton = app.f6Radio.SelectedObject;
            if(selectedButton == app.plotF6GainPhase)
                figure;
                plotGainPhase(app, app.fb6, app.fa6);
            elseif(selectedButton == app.plotF6Impulse)
                figure;
                plotImpulseResponce(app, app.fb6, app.fa6);
            elseif(selectedButton == app.plotF6Step)
                figure;
                plotStepResponce(app, app.fb6, app.fa6);
            elseif(selectedButton == app.plotF6pz)
                figure;
                plotPZmap(app, app.fb6, app.fa6);
            elseif(selectedButton == app.plotF6outTime)
                plotTimeDomain(app, app.f6Plot, app.band3k, app.soundFs);
            elseif(selectedButton == app.plotF6outFreq)
                plotFreqDomain(app, app.f6Plot, app.band3k, app.soundFs);
            end
        end

        % Selection changed function: f7Radio
        function f7RadioSelectionChanged(app, event)
            selectedButton = app.f7Radio.SelectedObject;
            if(selectedButton == app.plotF7GainPhase)
                figure;
                plotGainPhase(app, app.fb7, app.fa7);
            elseif(selectedButton == app.plotF7Impulse)
                figure;
                plotImpulseResponce(app, app.fb7, app.fa7);
            elseif(selectedButton == app.plotF7Step)
                figure;
                plotStepResponce(app, app.fb7, app.fa7);
            elseif(selectedButton == app.plotF7pz)
                figure;
                plotPZmap(app, app.fb7, app.fa7);
            elseif(selectedButton == app.plotF7outTime)
                plotTimeDomain(app, app.f7Plot, app.band6k, app.soundFs);
            elseif(selectedButton == app.plotF7outFreq)
                plotFreqDomain(app, app.f7Plot, app.band6k, app.soundFs);
            end
        end

        % Selection changed function: f8Radio
        function f8RadioSelectionChanged(app, event)
            selectedButton = app.f8Radio.SelectedObject;
            if(selectedButton == app.plotF8GainPhase)
                figure;
                plotGainPhase(app, app.fb8, app.fa8);
            elseif(selectedButton == app.plotF8Impulse)
                figure;
                plotImpulseResponce(app, app.fb8, app.fa8);
            elseif(selectedButton == app.plotF8Step)
                figure;
                plotStepResponce(app, app.fb8, app.fa8);
            elseif(selectedButton == app.plotF8pz)
                figure;
                plotPZmap(app, app.fb8, app.fa8);
            elseif(selectedButton == app.plotF8outTime)
                plotTimeDomain(app, app.f8Plot, app.band12k, app.soundFs);
            elseif(selectedButton == app.plotF8outFreq)
                plotFreqDomain(app, app.f8Plot, app.band12k, app.soundFs);
            end
        end

        % Selection changed function: f9Radio
        function f9RadioSelectionChanged(app, event)
            selectedButton = app.f9Radio.SelectedObject;
            if(selectedButton == app.plotF9GainPhase)
                figure;
                plotGainPhase(app, app.fb9, app.fa9);
            elseif(selectedButton == app.plotF9Impulse)
                figure;
                plotImpulseResponce(app, app.fb9, app.fa9);
            elseif(selectedButton == app.plotF9Step)
                figure;
                plotStepResponce(app, app.fb9, app.fa9);
            elseif(selectedButton == app.plotF9pz)
                figure;
                plotPZmap(app, app.fb9, app.fa9);
            elseif(selectedButton == app.plotF9outTime)
                plotTimeDomain(app, app.f9Plot, app.band14k, app.soundFs);
            elseif(selectedButton == app.plotF9outFreq)
                plotFreqDomain(app, app.f9Plot, app.band14k, app.soundFs);
            end
        end

        % Selection changed function: f10Radio
        function f10RadioSelectionChanged(app, event)
            selectedButton = app.f10Radio.SelectedObject;
            if(selectedButton == app.plotF10GainPhase)
                figure;
                plotGainPhase(app, app.fb10, app.fa10);
            elseif(selectedButton == app.plotF10Impulse)
                figure;
                plotImpulseResponce(app, app.fb10, app.fa10);
            elseif(selectedButton == app.plotF10Step)
                figure;
                plotStepResponce(app, app.fb10, app.fa10);
            elseif(selectedButton == app.plotF10pz)
                figure;
                plotPZmap(app, app.fb10, app.fa10);
            elseif(selectedButton == app.plotF10outTime)
                plotTimeDomain(app, app.f10Plot, app.band16k, app.soundFs);
            elseif(selectedButton == app.plotF10outFreq)
                plotFreqDomain(app, app.f10Plot, app.band16k, app.soundFs);
            end
        end

        % Button pushed function: stopBtn
        function stopBtnButtonPushed(app, event)
            stop(app.player);
            %clear playsnd;
        end

        % Button pushed function: playOriginalBtn
        function playOriginalBtnButtonPushed(app, event)
            app.player = audioplayer(app.inputSignal, app.inFs);
            stop(app.player);
            play(app.player);
        end

        % Button pushed function: resetBtn
        function resetBtnButtonPushed(app, event)
            app.outputSignal = app.inputSignal;
            app.soundFs = app.inFs;
            app.gain1 = 0;
            app.gain2 = 0;
            app.gain3 = 0;
            app.gain4 = 0;
            app.gain5 = 0;
            app.gain6 = 0;
            app.gain7 = 0;
            app.gain8 = 0;
            app.gain9 = 0;
            app.gain10 = 0;
            app.band1Slider.Value = 0;
            app.band2Slider.Value = 0;
            app.band3Slider.Value = 0;
            app.band4Slider.Value = 0;
            app.band5Slider.Value = 0;
            app.band6Slider.Value = 0;
            app.band7Slider.Value = 0;
            app.band8Slider.Value = 0;
            app.band9Slider.Value = 0;
            app.band10Slider.Value = 0;            
            app.OutputFskHzSpinner.Value = app.inFs/1000;
        end

        % Button pushed function: saveBtn
        function saveBtnButtonPushed(app, event)
            filter = {'.wav'};
            [name, path] = uiputfile(filter);
            app.AudioEquilizerUIFigure.Visible = false;
            app.AudioEquilizerUIFigure.Visible = true;
            if(name ~= 0)
                if(length(name) == 4)
                    name = 'untitled.wav';
                end
                directory = [path name];
                audiowrite(directory, app.outputSignal, app.soundFs);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioEquilizerUIFigure = uifigure('Visible', 'off');
            app.AudioEquilizerUIFigure.Name = 'Audio Equilizer';
            app.TabGroup = uitabgroup(app.AudioEquilizerUIFigure);
            app.EquilizerTab = uitab(app.TabGroup);
            app.EquilizerTab.Title = 'Equilizer';
            app.SliderLabel = uilabel(app.EquilizerTab);
            app.SliderLabel.Text = '0';
            app.band1Slider = uislider(app.EquilizerTab);
            app.band1Slider.Limits = [-12 12];
            app.band1Slider.ValueChangedFcn = createCallbackFcn(app, @band1SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.EquilizerTab);
            app.Slider_2Label.Text = '170';
            app.band2Slider = uislider(app.EquilizerTab);
            app.band2Slider.Limits = [-12 12];
            app.band2Slider.ValueChangedFcn = createCallbackFcn(app, @band2SliderValueChanged, true);
            app.Slider_3Label = uilabel(app.EquilizerTab);
            app.Slider_3Label.Text = '310';
            app.band3Slider = uislider(app.EquilizerTab);
            app.band3Slider.Limits = [-12 12];
            app.band3Slider.ValueChangedFcn = createCallbackFcn(app, @band3SliderValueChanged, true);
            app.Slider_4Label = uilabel(app.EquilizerTab);
            app.Slider_4Label.Text = '600';
            app.band4Slider = uislider(app.EquilizerTab);
            app.band4Slider.Limits = [-12 12];
            app.band4Slider.ValueChangedFcn = createCallbackFcn(app, @band4SliderValueChanged, true);
            app.kSliderLabel = uilabel(app.EquilizerTab);
            app.kSliderLabel.Text = '1k';
            app.band5Slider = uislider(app.EquilizerTab);
            app.band5Slider.Limits = [-12 12];
            app.band5Slider.ValueChangedFcn = createCallbackFcn(app, @band5SliderValueChanged, true);
            app.kSlider_2Label = uilabel(app.EquilizerTab);
            app.kSlider_2Label.Text = '3k';
            app.band6Slider = uislider(app.EquilizerTab);
            app.band6Slider.Limits = [-12 12];
            app.band6Slider.ValueChangedFcn = createCallbackFcn(app, @band6SliderValueChanged, true);
            app.kSlider_3Label = uilabel(app.EquilizerTab);
            app.kSlider_3Label.Text = '6k';
            app.band7Slider = uislider(app.EquilizerTab);
            app.band7Slider.Limits = [-12 12];
            app.band7Slider.ValueChangedFcn = createCallbackFcn(app, @band7SliderValueChanged, true);
            app.kSlider_4Label = uilabel(app.EquilizerTab);
            app.kSlider_4Label.Text = '12k';
            app.band8Slider = uislider(app.EquilizerTab);
            app.band8Slider.Limits = [-12 12];
            app.band8Slider.ValueChangedFcn = createCallbackFcn(app, @band8SliderValueChanged, true);
            app.kSlider_5Label = uilabel(app.EquilizerTab);
            app.kSlider_5Label.Text = '14k';
            app.band9Slider = uislider(app.EquilizerTab);
            app.band9Slider.Limits = [-12 12];
            app.band9Slider.ValueChangedFcn = createCallbackFcn(app, @band9SliderValueChanged, true);
            app.kLabel = uilabel(app.EquilizerTab);
            app.kLabel.Text = '16k';
            app.band10Slider = uislider(app.EquilizerTab);
            app.band10Slider.Limits = [-12 12];
            app.band10Slider.ValueChangedFcn = createCallbackFcn(app, @band10SliderValueChanged, true);
            app.FilterTypeSwitch = uiswitch(app.EquilizerTab, 'toggle');
            app.FilterTypeSwitch.Items = {'IIR', 'FIR'};
            app.FilterTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterTypeSwitchValueChanged, true);
            app.FilterTypeSwitch.Value = 'IIR';
            app.FIR_lamp = uilamp(app.EquilizerTab);
            app.IIR_lamp = uilamp(app.EquilizerTab);
            app.GaindbLabel = uilabel(app.EquilizerTab);
            app.GaindbLabel.Text = 'Gain (db)';
            app.saveBtn = uibutton(app.EquilizerTab, 'push');
            app.saveBtn.ButtonPushedFcn = createCallbackFcn(app, @saveBtnButtonPushed, true);
            app.saveBtn.Enable = 'off';
            app.saveBtn.Text = 'Save';
            app.chooseBtn = uibutton(app.EquilizerTab, 'push');
            app.chooseBtn.ButtonPushedFcn = createCallbackFcn(app, @chooseBtnButtonPushed, true);
            app.chooseBtn.Text = 'Open';
            app.OutputFskHzSpinnerLabel = uilabel(app.EquilizerTab);
            app.OutputFskHzSpinnerLabel.Text = 'Output Fs. (kHz)';
            app.OutputFskHzSpinner = uispinner(app.EquilizerTab);
            app.OutputFskHzSpinner.Limits = [0 Inf];
            app.OutputFskHzSpinner.ValueChangedFcn = createCallbackFcn(app, @OutputFskHzSpinnerValueChanged, true);
            app.OutputFskHzSpinner.Value = 1;
            app.applyBtn = uibutton(app.EquilizerTab, 'push');
            app.applyBtn.ButtonPushedFcn = createCallbackFcn(app, @applyBtnButtonPushed, true);
            app.applyBtn.Enable = 'off';
            app.applyBtn.Text = 'Apply';
            app.playBtn = uibutton(app.EquilizerTab, 'push');
            app.playBtn.ButtonPushedFcn = createCallbackFcn(app, @playBtnButtonPushed, true);
            app.playBtn.Enable = 'off';
            app.playBtn.Text = 'Play';
            app.stopBtn = uibutton(app.EquilizerTab, 'push');
            app.stopBtn.ButtonPushedFcn = createCallbackFcn(app, @stopBtnButtonPushed, true);
            app.stopBtn.Enable = 'off';
            app.stopBtn.Text = 'Stop';
            app.playOriginalBtn = uibutton(app.EquilizerTab, 'push');
            app.playOriginalBtn.ButtonPushedFcn = createCallbackFcn(app, @playOriginalBtnButtonPushed, true);
            app.playOriginalBtn.Enable = 'off';
            app.playOriginalBtn.Text = 'Play original';
            app.resetBtn = uibutton(app.EquilizerTab, 'push');
            app.resetBtn.ButtonPushedFcn = createCallbackFcn(app, @resetBtnButtonPushed, true);
            app.resetBtn.Enable = 'off';
            app.resetBtn.Text = 'Reset';
            app.InputVSOutputTab = uitab(app.TabGroup);
            app.InputVSOutputTab.Title = 'Input VS Output';
            app.plotInOut = uiaxes(app.InputVSOutputTab);
            title(app.plotInOut, 'Plot')
            xlabel(app.plotInOut, '')
            ylabel(app.plotInOut, '')
            app.inOutRadio = uibuttongroup(app.InputVSOutputTab);
            app.inOutRadio.SelectionChangedFcn = createCallbackFcn(app, @inOutRadioSelectionChanged, true);
            app.inOutRadio.Visible = 'off';
            app.InputtimedomainButton = uiradiobutton(app.inOutRadio);
            app.InputtimedomainButton.Text = 'Input time domain';
            app.InputtimedomainButton.Value = true;
            app.InputfrequencydomainButton = uiradiobutton(app.inOutRadio);
            app.InputfrequencydomainButton.Text = 'Input frequency domain';
            app.OutputtimedomainButton = uiradiobutton(app.inOutRadio);
            app.OutputtimedomainButton.Text = 'Output time domain';
            app.OutputfrequencydomainButton = uiradiobutton(app.inOutRadio);
            app.OutputfrequencydomainButton.Text = 'Output frequency domain';
            app.InputOutputSignalsanalysisLabel = uilabel(app.InputVSOutputTab);
            app.InputOutputSignalsanalysisLabel.Text = {'Input & Output Signals'; 'analysis:'};
            app.Filter1Tab = uitab(app.TabGroup);
            app.Filter1Tab.Title = 'Filter 1';
            app.f1Plot = uiaxes(app.Filter1Tab);
            title(app.f1Plot, 'Plot')
            xlabel(app.f1Plot, '')
            ylabel(app.f1Plot, '')
            app.f1Radio = uibuttongroup(app.Filter1Tab);
            app.f1Radio.SelectionChangedFcn = createCallbackFcn(app, @f1RadioSelectionChanged, true);
            app.f1Radio.Visible = 'off';
            app.plotF1GainPhase = uiradiobutton(app.f1Radio);
            app.plotF1GainPhase.Text = 'Gain & Phase';
            app.plotF1GainPhase.Value = true;
            app.plotF1Impulse = uiradiobutton(app.f1Radio);
            app.plotF1Impulse.Text = 'Impulse responce';
            app.plotF1Step = uiradiobutton(app.f1Radio);
            app.plotF1Step.Text = 'Step responce';
            app.plotF1pz = uiradiobutton(app.f1Radio);
            app.plotF1pz.Text = 'Poles & zeros';
            app.plotF1outTime = uiradiobutton(app.f1Radio);
            app.plotF1outTime.Text = 'Output time domain';
            app.plotF1outFreq = uiradiobutton(app.f1Radio);
            app.plotF1outFreq.Text = 'Output frequency domain';
            app.Hzto170HzfilteranalysisLabel = uilabel(app.Filter1Tab);
            app.Hzto170HzfilteranalysisLabel.Text = {'0 Hz to 170 Hz filter'; 'analysis:'};
            app.Filter2Tab = uitab(app.TabGroup);
            app.Filter2Tab.Title = 'Filter 2';
            app.f2Plot = uiaxes(app.Filter2Tab);
            title(app.f2Plot, 'Plot')
            xlabel(app.f2Plot, '')
            ylabel(app.f2Plot, '')
            app.f2Radio = uibuttongroup(app.Filter2Tab);
            app.f2Radio.SelectionChangedFcn = createCallbackFcn(app, @f2RadioSelectionChanged, true);
            app.f2Radio.Visible = 'off';
            app.plotF2GainPhase = uiradiobutton(app.f2Radio);
            app.plotF2GainPhase.Text = 'Gain & Phase';
            app.plotF2GainPhase.Value = true;
            app.plotF2Impulse = uiradiobutton(app.f2Radio);
            app.plotF2Impulse.Text = 'Impulse responce';
            app.plotF2Step = uiradiobutton(app.f2Radio);
            app.plotF2Step.Text = 'Step responce';
            app.plotF2pz = uiradiobutton(app.f2Radio);
            app.plotF2pz.Text = 'Poles & zeros';
            app.plotF2outTime = uiradiobutton(app.f2Radio);
            app.plotF2outTime.Text = 'Output time domain';
            app.plotF2outFreq = uiradiobutton(app.f2Radio);
            app.plotF2outFreq.Text = 'Output frequency domain';
            app.Hzto310HzfilteranalysisLabel = uilabel(app.Filter2Tab);
            app.Hzto310HzfilteranalysisLabel.Text = {'170 Hz to 310 Hz filter'; 'analysis:'};
            app.Filter3Tab = uitab(app.TabGroup);
            app.Filter3Tab.Title = 'Filter 3';
            app.f3Plot = uiaxes(app.Filter3Tab);
            title(app.f3Plot, 'Plot')
            xlabel(app.f3Plot, '')
            ylabel(app.f3Plot, '')
            app.f3Radio = uibuttongroup(app.Filter3Tab);
            app.f3Radio.SelectionChangedFcn = createCallbackFcn(app, @f3RadioSelectionChanged, true);
            app.f3Radio.Visible = 'off';
            app.plotF3GainPhase = uiradiobutton(app.f3Radio);
            app.plotF3GainPhase.Text = 'Gain & Phase';
            app.plotF3GainPhase.Value = true;
            app.plotF3Impulse = uiradiobutton(app.f3Radio);
            app.plotF3Impulse.Text = 'Impulse responce';
            app.plotF3Step = uiradiobutton(app.f3Radio);
            app.plotF3Step.Text = 'Step responce';
            app.plotF3pz = uiradiobutton(app.f3Radio);
            app.plotF3pz.Text = 'Poles & zeros';
            app.plotF3outTime = uiradiobutton(app.f3Radio);
            app.plotF3outTime.Text = 'Output time domain';
            app.plotF3outFreq = uiradiobutton(app.f3Radio);
            app.plotF3outFreq.Text = 'Output frequency domain';
            app.Hzto600HzfilteranalysisLabel = uilabel(app.Filter3Tab);
            app.Hzto600HzfilteranalysisLabel.Text = {'310 Hz to 600 Hz filter'; 'analysis:'};
            app.Filter4Tab = uitab(app.TabGroup);
            app.Filter4Tab.Title = 'Filter 4';
            app.f4Plot = uiaxes(app.Filter4Tab);
            title(app.f4Plot, 'Plot')
            xlabel(app.f4Plot, '')
            ylabel(app.f4Plot, '')
            app.f4Radio = uibuttongroup(app.Filter4Tab);
            app.f4Radio.SelectionChangedFcn = createCallbackFcn(app, @f4RadioSelectionChanged, true);
            app.f4Radio.Visible = 'off';
            app.plotF4GainPhase = uiradiobutton(app.f4Radio);
            app.plotF4GainPhase.Text = 'Gain & Phase';
            app.plotF4GainPhase.Value = true;
            app.plotF4Impulse = uiradiobutton(app.f4Radio);
            app.plotF4Impulse.Text = 'Impulse responce';
            app.plotF4Step = uiradiobutton(app.f4Radio);
            app.plotF4Step.Text = 'Step responce';
            app.plotF4pz = uiradiobutton(app.f4Radio);
            app.plotF4pz.Text = 'Poles & zeros';
            app.plotF4outTime = uiradiobutton(app.f4Radio);
            app.plotF4outTime.Text = 'Output time domain';
            app.plotF4outFreq = uiradiobutton(app.f4Radio);
            app.plotF4outFreq.Text = 'Output frequency domain';
            app.Hzto1kHzfilteranalysisLabel = uilabel(app.Filter4Tab);
            app.Hzto1kHzfilteranalysisLabel.Text = {'600 Hz to 1 kHz filter'; 'analysis:'};
            app.Filter5Tab = uitab(app.TabGroup);
            app.Filter5Tab.Title = 'Filter 5';
            app.f5Plot = uiaxes(app.Filter5Tab);
            title(app.f5Plot, 'Plot')
            xlabel(app.f5Plot, '')
            ylabel(app.f5Plot, '')
            app.f5Radio = uibuttongroup(app.Filter5Tab);
            app.f5Radio.SelectionChangedFcn = createCallbackFcn(app, @f5RadioSelectionChanged, true);
            app.f5Radio.Visible = 'off';
            app.plotF5GainPhase = uiradiobutton(app.f5Radio);
            app.plotF5GainPhase.Text = 'Gain & Phase';
            app.plotF5GainPhase.Value = true;
            app.plotF5Impulse = uiradiobutton(app.f5Radio);
            app.plotF5Impulse.Text = 'Impulse responce';
            app.plotF5Step = uiradiobutton(app.f5Radio);
            app.plotF5Step.Text = 'Step responce';
            app.plotF5pz = uiradiobutton(app.f5Radio);
            app.plotF5pz.Text = 'Poles & zeros';
            app.plotF5outTime = uiradiobutton(app.f5Radio);
            app.plotF5outTime.Text = 'Output time domain';
            app.plotF5outFreq = uiradiobutton(app.f5Radio);
            app.plotF5outFreq.Text = 'Output frequency domain';
            app.kHzto3kHzfilteranalysisLabel = uilabel(app.Filter5Tab);
            app.kHzto3kHzfilteranalysisLabel.Text = {'1 kHz to 3 kHz filter'; 'analysis:'};
            app.Filter6Tab = uitab(app.TabGroup);
            app.Filter6Tab.Title = 'Filter 6';
            app.f6Plot = uiaxes(app.Filter6Tab);
            title(app.f6Plot, 'Plot')
            xlabel(app.f6Plot, '')
            ylabel(app.f6Plot, '')
            app.f6Radio = uibuttongroup(app.Filter6Tab);
            app.f6Radio.SelectionChangedFcn = createCallbackFcn(app, @f6RadioSelectionChanged, true);
            app.f6Radio.Visible = 'off';
            app.plotF6GainPhase = uiradiobutton(app.f6Radio);
            app.plotF6GainPhase.Text = 'Gain & Phase';
            app.plotF6GainPhase.Value = true;
            app.plotF6Impulse = uiradiobutton(app.f6Radio);
            app.plotF6Impulse.Text = 'Impulse responce';
            app.plotF6Step = uiradiobutton(app.f6Radio);
            app.plotF6Step.Text = 'Step responce';
            app.plotF6pz = uiradiobutton(app.f6Radio);
            app.plotF6pz.Text = 'Poles & zeros';
            app.plotF6outTime = uiradiobutton(app.f6Radio);
            app.plotF6outTime.Text = 'Output time domain';
            app.plotF6outFreq = uiradiobutton(app.f6Radio);
            app.plotF6outFreq.Text = 'Output frequency domain';
            app.kHzto6kHzfilteranalysisLabel = uilabel(app.Filter6Tab);
            app.kHzto6kHzfilteranalysisLabel.Text = {'3 kHz to 6 kHz filter'; 'analysis:'};
            app.Filter7Tab = uitab(app.TabGroup);
            app.Filter7Tab.Title = 'Filter 7';
            app.f7Plot = uiaxes(app.Filter7Tab);
            title(app.f7Plot, 'Plot')
            xlabel(app.f7Plot, '')
            ylabel(app.f7Plot, '')
            app.f7Radio = uibuttongroup(app.Filter7Tab);
            app.f7Radio.SelectionChangedFcn = createCallbackFcn(app, @f7RadioSelectionChanged, true);
            app.f7Radio.Visible = 'off';
            app.plotF7GainPhase = uiradiobutton(app.f7Radio);
            app.plotF7GainPhase.Text = 'Gain & Phase';
            app.plotF7GainPhase.Value = true;
            app.plotF7Impulse = uiradiobutton(app.f7Radio);
            app.plotF7Impulse.Text = 'Impulse responce';
            app.plotF7Step = uiradiobutton(app.f7Radio);
            app.plotF7Step.Text = 'Step responce';
            app.plotF7pz = uiradiobutton(app.f7Radio);
            app.plotF7pz.Text = 'Poles & zeros';
            app.plotF7outTime = uiradiobutton(app.f7Radio);
            app.plotF7outTime.Text = 'Output time domain';
            app.plotF7outFreq = uiradiobutton(app.f7Radio);
            app.plotF7outFreq.Text = 'Output frequency domain';
            app.kHzto12kHzfilteranalysisLabel = uilabel(app.Filter7Tab);
            app.kHzto12kHzfilteranalysisLabel.Text = {'6 kHz to 12 kHz filter'; 'analysis:'};
            app.Filter8Tab = uitab(app.TabGroup);
            app.Filter8Tab.Title = 'Filter 8';
            app.f8Plot = uiaxes(app.Filter8Tab);
            title(app.f8Plot, 'Plot')
            xlabel(app.f8Plot, '')
            ylabel(app.f8Plot, '')
            app.f8Radio = uibuttongroup(app.Filter8Tab);
            app.f8Radio.SelectionChangedFcn = createCallbackFcn(app, @f8RadioSelectionChanged, true);
            app.f8Radio.Visible = 'off';
            app.plotF8GainPhase = uiradiobutton(app.f8Radio);
            app.plotF8GainPhase.Text = 'Gain & Phase';
            app.plotF8GainPhase.Value = true;
            app.plotF8Impulse = uiradiobutton(app.f8Radio);
            app.plotF8Impulse.Text = 'Impulse responce';
            app.plotF8Step = uiradiobutton(app.f8Radio);
            app.plotF8Step.Text = 'Step responce';
            app.plotF8pz = uiradiobutton(app.f8Radio);
            app.plotF8pz.Text = 'Poles & zeros';
            app.plotF8outTime = uiradiobutton(app.f8Radio);
            app.plotF8outTime.Text = 'Output time domain';
            app.plotF8outFreq = uiradiobutton(app.f8Radio);
            app.plotF8outFreq.Text = 'Output frequency domain';
            app.kHzto14kHzfilteranalysisLabel = uilabel(app.Filter8Tab);
            app.kHzto14kHzfilteranalysisLabel.Text = {'12 kHz to 14 kHz filter'; 'analysis:'};
            app.Filter9Tab = uitab(app.TabGroup);
            app.Filter9Tab.Title = 'Filter 9';
            app.f9Plot = uiaxes(app.Filter9Tab);
            title(app.f9Plot, 'Plot')
            xlabel(app.f9Plot, '')
            ylabel(app.f9Plot, '')
            app.f9Radio = uibuttongroup(app.Filter9Tab);
            app.f9Radio.SelectionChangedFcn = createCallbackFcn(app, @f9RadioSelectionChanged, true);
            app.f9Radio.Visible = 'off';
            app.plotF9GainPhase = uiradiobutton(app.f9Radio);
            app.plotF9GainPhase.Text = 'Gain & Phase';
            app.plotF9GainPhase.Value = true;
            app.plotF9Impulse = uiradiobutton(app.f9Radio);
            app.plotF9Impulse.Text = 'Impulse responce';
            app.plotF9Step = uiradiobutton(app.f9Radio);
            app.plotF9Step.Text = 'Step responce';
            app.plotF9pz = uiradiobutton(app.f9Radio);
            app.plotF9pz.Text = 'Poles & zeros';
            app.plotF9outTime = uiradiobutton(app.f9Radio);
            app.plotF9outTime.Text = 'Output time domain';
            app.plotF9outFreq = uiradiobutton(app.f9Radio);
            app.plotF9outFreq.Text = 'Output frequency domain';
            app.kHzto16kHzfilteranalysisLabel = uilabel(app.Filter9Tab);
            app.kHzto16kHzfilteranalysisLabel.Text = {'14 kHz to 16 kHz filter'; 'analysis:'};
            app.Filter10Tab = uitab(app.TabGroup);
            app.Filter10Tab.Title = 'Filter 10';
            app.f10Plot = uiaxes(app.Filter10Tab);
            title(app.f10Plot, 'Plot')
            xlabel(app.f10Plot, '')
            ylabel(app.f10Plot, '')
            app.f10Radio = uibuttongroup(app.Filter10Tab);
            app.f10Radio.SelectionChangedFcn = createCallbackFcn(app, @f10RadioSelectionChanged, true);
            app.f10Radio.Visible = 'off';
            app.plotF10GainPhase = uiradiobutton(app.f10Radio);
            app.plotF10GainPhase.Text = 'Gain & Phase';
            app.plotF10GainPhase.Value = true;
            app.plotF10Impulse = uiradiobutton(app.f10Radio);
            app.plotF10Impulse.Text = 'Impulse responce';
            app.plotF10Step = uiradiobutton(app.f10Radio);
            app.plotF10Step.Text = 'Step responce';
            app.plotF10pz = uiradiobutton(app.f10Radio);
            app.plotF10pz.Text = 'Poles & zeros';
            app.plotF10outTime = uiradiobutton(app.f10Radio);
            app.plotF10outTime.Text = 'Output time domain';
            app.plotF10outFreq = uiradiobutton(app.f10Radio);
            app.plotF10outFreq.Text = 'Output frequency domain';
            app.above16kHzfilteranalysisLabel = uilabel(app.Filter10Tab);
            app.above16kHzfilteranalysisLabel.Text = {'above 16 kHz filter'; 'analysis:'};
            app.ChooseasoundfiletoeditLabel = uilabel(app.AudioEquilizerUIFigure);
            app.ChooseasoundfiletoeditLabel.Text = 'Choose a sound file to edit:';
            % Show the figure after all components are created
            app.AudioEquilizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioEquilizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioEquilizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioEquilizerUIFigure)
        end
    end
end